classdef artery_vein_labeler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        redoButton         matlab.ui.control.Button
        undoButton         matlab.ui.control.Button
        showlinesCheckBox  matlab.ui.control.CheckBox
        loadButton         matlab.ui.control.Button
        saveButton         matlab.ui.control.Button
        OKButton           matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        img_name 
        mask_name
        img
        mask
        img_image
        splitmask
        min_len
        contour_line_width
        contours
        history_len
        history
        click_on
        label
        user_name
        colors
        history_position 
        tmp_img
    end
    
    methods (Access = public)
        
        function show_img(app)
            hold(app.UIAxes, 'off')
            app.img_image = imshow(mat2gray(app.img,[0,255]),'Parent',app.UIAxes);
            
        end
        
        function show_lines(app)
            
            for k=1:length(app.contours)
                c=app.contours(k);
                delete(c)
            end


            hold(app.UIAxes, 'on')
            app.contours = {};
            for k = 1:length(app.colors)
                tmp = visboundaries(app.UIAxes,app.splitmask==k,'Color',app.colors{k},'EnhanceVisibility',0,'LineWidth',app.contour_line_width);
                app.contours=[app.contours,tmp];
            end
            
            hold(app.UIAxes, 'off')
            
        end
        function add_line(app,object,color_num)
            hold(app.UIAxes, 'on')
            

            tmp = visboundaries(app.UIAxes,object,'Color',app.colors{color_num},'EnhanceVisibility',0,'LineWidth',app.contour_line_width);
            app.contours=[app.contours,tmp];
            
            hold(app.UIAxes, 'off')
        end
        
        function make_label_img(app)
            
            app.label = bwlabel(app.splitmask>0,8);
            
        end
        function update_history(app)
            app.history_position = app.history_position+1;
            if app.history_position>app.history_len
                app.history_position = 1;  
                app.show_lines();
            end
            app.history{app.history_position} = app.splitmask;
            
        end
        function load_history(app)
            
            app.splitmask = app.history{app.history_position};
            app.make_label_img()
            app.show_lines();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, img_name, mask_name, user_name)
            app.contour_line_width = 1;
            app.min_len = 40;
            app.history_len=10;
            app.colors = {'m','r','b'};
             
            app.click_on = 1;
            app.contours = [];
            app.tmp_img = [];
            
            
            app.user_name = user_name;
            app.img_name = img_name;
            app.mask_name = mask_name;
            app.img = double(imread(img_name));
            app.mask = imread(mask_name)>0;
            
           
            app.splitmask = split_mask(app.mask,app.min_len);
            app.make_label_img()
                 
            app.history = {};
            for k = 1:app.history_len
                app.history{k} = app.splitmask;
            end
            app.history_position = 1;
            
            app.show_img()
            app.show_lines()
            
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            [~,tmp,~] = fileparts(img_name);
            tmp = replace(tmp,'_','\_');
            title(app.UIAxes,tmp)
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if app.click_on==0
                return
            end
            if isequal(event.Source.CurrentObject.Parent,app.UIAxes)==0
                return
            end
                
             position=app.UIFigure.CurrentObject.Parent.CurrentPoint;
             position=round([position(1,1),position(1,2)]);
             
             tmp = app.label(position(2),position(1));
             if tmp==0
                 return
             end
             object = app.label==tmp;


             if strcmp(app.UIFigure.SelectionType,'normal')
                 tmp =2;
             elseif strcmp(app.UIFigure.SelectionType,'alt')
                 tmp = 3;
             elseif strcmp(app.UIFigure.SelectionType,'extend')
                 tmp = 1;
             end
             app.splitmask(object) = tmp;
             app.add_line(object,tmp);
             app.update_history()
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            strtime_tmp = replace(replace(datestr(now),' ','_'),':','_'); 
            default_name = [replace(app.img_name,'.JPG','') '_' app.user_name '_' strtime_tmp '.png'];
            
            
            [file,path] = uiputfile('.tif','Save results', default_name);
            
            imwrite(uint8(app.splitmask),[path filesep file]);
            
            
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            [file,path] = uigetfile('.png','Load results');
            
            app.splitmask = double(imread([path filesep file]));
            app.make_label_img()

            app.show_img()
            app.show_lines()

        end

        % Button pushed function: undoButton
        function undoButtonPushed(app, event)
            app.history_position = app.history_position -1;
            if app.history_position==0
                app.history_position = app.history_len;
            end
            app.load_history()
        end

        % Button pushed function: redoButton
        function redoButtonPushed(app, event)
            app.history_position = app.history_position + 1;
            if app.history_position>app.history_len
                app.history_position = 1;
            end
            app.load_history()
        end

        % Value changed function: showlinesCheckBox
        function showlinesCheckBoxValueChanged(app, event)
            value = app.showlinesCheckBox.Value;
            
            if value
                delete(app.tmp_img)
            else
                hold(app.UIAxes, 'on')
                app.tmp_img = imshow(mat2gray(app.img,[0,255]),'Parent',app.UIAxes);
                hold(app.UIAxes, 'off')
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.Text = 'OK';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.showlinesCheckBox = uicheckbox(app.UIFigure);
            app.showlinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @showlinesCheckBoxValueChanged, true);
            app.showlinesCheckBox.Text = 'show lines';
            app.showlinesCheckBox.Value = true;
            app.undoButton = uibutton(app.UIFigure, 'push');
            app.undoButton.ButtonPushedFcn = createCallbackFcn(app, @undoButtonPushed, true);
            app.undoButton.Text = 'undo';
            app.redoButton = uibutton(app.UIFigure, 'push');
            app.redoButton.ButtonPushedFcn = createCallbackFcn(app, @redoButtonPushed, true);
            app.redoButton.Text = 'redo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = artery_vein_labeler(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end